classdef raphsodeeAcousticTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Slider_InitialPosition          matlab.ui.control.Slider
        PositionLabel                   matlab.ui.control.Label
        SliderMagnification             matlab.ui.control.Slider
        MagnifyLabel                    matlab.ui.control.Label
        SpecialistlauncherPanel         matlab.ui.container.Panel
        SpecialistDropDown              matlab.ui.control.DropDown
        SpecialistDropDownLabel         matlab.ui.control.Label
        OnlinemeasurementPanel          matlab.ui.container.Panel
        SAVEdataButton                  matlab.ui.control.Button
        MaximumdutaionminDropDown       matlab.ui.control.DropDown
        MaximumdutaionminDropDownLabel  matlab.ui.control.Label
        ShaperDropDown                  matlab.ui.control.DropDown
        ShaperDropDownLabel             matlab.ui.control.Label
        LabelSigCount                   matlab.ui.control.Label
        PeriodmsEditField               matlab.ui.control.EditField
        PeriodmsEditFieldLabel          matlab.ui.control.Label
        BaselengthDropDown              matlab.ui.control.DropDown
        BaselengthDropDownLabel         matlab.ui.control.Label
        STOPButton                      matlab.ui.control.Button
        STARTButton                     matlab.ui.control.Button
        TypeDropDown                    matlab.ui.control.DropDown
        TypeDropDownLabel               matlab.ui.control.Label
        RecordingLamp                   matlab.ui.control.Lamp
        RecordingLampLabel              matlab.ui.control.Label
        VoiceMemoCountdown              matlab.ui.control.Label
        Voicememo5sButton               matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
        TextAreaLabel                   matlab.ui.control.Label
        CalibrationPanel                matlab.ui.container.Panel
        TestsignalSPLCheckBox           matlab.ui.control.CheckBox
        OKcalibrationcompletedButton    matlab.ui.control.Button
        MaximuminputSPLdBDropDown       matlab.ui.control.DropDown
        MaximuminputSPLdBDropDownLabel  matlab.ui.control.Label
        TestsignalSPLdBDropDown         matlab.ui.control.DropDown
        TestsignalSPLdBDropDownLabel    matlab.ui.control.Label
        AbortButton                     matlab.ui.control.Button
        Label_time                      matlab.ui.control.Label
        TimesLabel                      matlab.ui.control.Label
        CalibrationButton               matlab.ui.control.Button
        dBreMSBLabel                    matlab.ui.control.Label
        LabelforDB                      matlab.ui.control.Label
        AudiointerfacePanel             matlab.ui.container.Panel
        SamplingfrequencyHzDropDown     matlab.ui.control.DropDown
        SamplingfrequencyHzDropDownLabel  matlab.ui.control.Label
        BitDepthDropDown                matlab.ui.control.DropDown
        BitDepthDropDownLabel           matlab.ui.control.Label
        BuffersizesamplesDropDown       matlab.ui.control.DropDown
        BuffersizesamplesDropDownLabel  matlab.ui.control.Label
        DeviceDropDown                  matlab.ui.control.DropDown
        DeviceDropDownLabel             matlab.ui.control.Label
        CAPRICEPexSGacousticmeasurementtoolRHAPSODEELabel  matlab.ui.control.Label
        UIAxes_Powerdecay               matlab.ui.control.UIAxes
        UIAxesFrequency                 matlab.ui.control.UIAxes
    end


    %       Copyright 2023 Hideki Kawahara
    %
    %   Licensed under the Apache License, Version 2.0 (the "License");
    %   you may not use this file except in compliance with the License.
    %   You may obtain a copy of the License at
    %
    %       http://www.apache.org/licenses/LICENSE-2.0
    %
    %   Unless required by applicable law or agreed to in writing, software
    %   distributed under the License is distributed on an "AS IS" BASIS,
    %   WITHOUT WARRANTIES OR CONDITIONS OF ANY KIND, either express or implied.
    %   See the License for the specific language governing permissions and
    %   limitations under the License.

    properties (Access = private)
        CommonData % Description
    end

    properties (Access = public)
    end

    methods (Access = private)

        function results = setReportDirectory(app)
            selpath = uigetdir(pwd,"フォルダーを作る場所を選んで下さい");
            dirPath = append(selpath,"/",app.CommonData.reportDirName);
            mkdir(dirPath);
            app.CommonData.logFileName = "revSpTestLog.txt";
            fullPathName = append(dirPath,"/",app.CommonData.logFileName);
            app.CommonData.logFid = fopen(fullPathName,"a");
            fprintf(app.CommonData.logFid, 'Start, %s\n', datetime);
            app.CommonData.resultDir = dirPath;
            %app.CommonData.logFileFID =
            results = 1;
        end

        function results = ioPlayRecDeviceAssignAndTest(app)
            %delimiter = ", ";
            fs = str2double(app.SamplingfrequencyHzDropDown.Value);
            ioBufferSize = str2double(app.BuffersizesamplesDropDown.Value);
            bitDepth = app.BitDepthDropDown.Value;
            ioDevice = app.DeviceDropDown.Value;
            try
                app.CommonData.playrec = audioPlayerRecorder(fs,"BitDepth",...
                    bitDepth,"BufferSize",ioBufferSize,...
                    "Device",ioDevice,"SupportVariableSize",true);
            catch
                app.TextArea.Value = ["インタフェースの初期化に失敗しました";...
                    "別のオーディオインタフェース（Device）を選んで下さい"];
                app.DeviceDropDown.Enable = "on";
                return;
            end
            app.CommonData.ioDevice = ioDevice;
            switch app.CommonData.calibrationdB
                case 0
                    app.TextArea.Value = ["インタフェースが正しく設定されたことを確認しました"; ...
                        "よければ、システムの較正に進みます"; ...
                        "下のパネルの Calibration ボタンを押して下さい。較正用の音が出ます"];
%                    fprintf(app.CommonData.logFid, 'IOdevice selection, %s\n', ...
%                        append(ioDevice, delimiter, string(dat;etime)));
                    app.DeviceDropDown.Enable = "off";
                    release(app.CommonData.playrec);
                    app.CalibrationPanel.Enable = "on";
                    app.CalibrationPanel.Visible = "on";
                    app.CalibrationButton.Enable = "on";
                    app.Voicememo5sButton.Visible = "on";
                    app.Voicememo5sButton.Enable = "on";
                    app.DeviceDropDown.Enable = "off";
                    initializeGraphics(app);
                otherwise
                    app.TextArea.Value = ["インタフェースが正しく設定されたことを確認しました"; ...
                        "測定を続けて下さい、必要があれば較正して下さい"];
            end
            results = 1;
        end

        function results = calibrateInput(app)
            fs = str2double(app.SamplingfrequencyHzDropDown.Value);
            ioBufferSize = str2double(app.BuffersizesamplesDropDown.Value);
            bitDepth = app.BitDepthDropDown.Value;
            ioDevice = app.DeviceDropDown.Value;
            playrec = audioPlayerRecorder(fs,"BitDepth",...
                bitDepth,"BufferSize",ioBufferSize,...
                "Device",ioDevice,"SupportVariableSize",true, ...
                "RecorderChannelMapping",1,"PlayerChannelMapping",[1,2]);
            t1sec = (1:round(fs))'/fs;
            fc = 1000;
            fL = round(fc*2^(-1/2));
            fU = round(fc*2^(1/2));
            xnUnit = zeros(round(fs),1);
            for fcur = fL:fU
                xnUnit = xnUnit + sin(2*pi*fcur*t1sec+rand(1,1)*2*pi);
            end
            xnUnit = xnUnit/std(xnUnit)/10; % -20dB rel. MSB
            signalDuration = 600;
            xTest = zeros(round(fs)*signalDuration,1);
            for ii = 1:signalDuration
                xTest((ii-1)*round(fs)+(1:round(fs))) = xnUnit;
            end
            app.CommonData.xnUnit = xnUnit;
            app.CommonData.testSignal = xTest;
            %-----------------
            pause(0.5);
            app.CommonData.running = "on";
            app.CommonData.aborted = "no";
            app.AbortButton.Enable = "on";
            app.TestsignalSPLdBDropDown.Enable = "on";
            app.MaximuminputSPLdBDropDown.Enable = "on";
            count = 0;
            maxCount = floor(length(xTest)/ioBufferSize);
            app.CommonData.currntLevel = -100;
            oneSecBuffer = zeros(round(fs),1);
            while app.CommonData.running == "on" && count < maxCount
                oneSecBuffer(1:end-ioBufferSize) = ...
                    oneSecBuffer(ioBufferSize+1:end);
                outData = xTest(count*ioBufferSize+(1:ioBufferSize));
                readData = playrec([outData outData]);
                oneSecBuffer(end-ioBufferSize+1:end) = readData;
                app.CommonData.currntLevel = 20*log10(std(oneSecBuffer));
                app.LabelforDB.Text = ...
                    num2str(app.CommonData.currntLevel,"%5.1f");
                count = count + 1;
                app.Label_time.Text = num2str(count*ioBufferSize/fs,"%5.2f");
                drawnow
            end
            release(playrec);
            if app.CommonData.running == "on"
                app.TextArea.Value = ["試験信号が終わりました"; ...
                    "Calibration ボタンを押すと、また較正することができます"; ...
                    "1秒後に Calibration ボタンを押すことができるようになります"];
                pause(1)
                app.CalibrationButton.Enable = "on";
                app.AbortButton.Enable = "off";
            elseif app.CommonData.aborted == "yes"
                app.TextArea.Value = ["較正が中断されました"; ...
                    "Calibration ボタンを押すと、また較正することができます"; ...
                    "1秒後に Calibration ボタンを押すことができるようになります"];
                pause(1)
                app.CalibrationButton.Enable = "on";
                app.AbortButton.Enable = "off";
            end
            results = 1;
        end


        function results = initializeGraphics(app)
            if app.OnlinemeasurementPanel.Enable == "on"
                app.UIAxesFrequency.Visible = "on";
                app.UIAxes_Powerdecay.Visible = "on";
                app.SliderMagnification.Visible = "on";
                app.Slider_InitialPosition.Visible = "on";
                fs = str2double(app.SamplingfrequencyHzDropDown.Value);
                maxLevel = str2double(app.MaximuminputSPLdBDropDown.Value);
                minLevel = 0;
                fL = 16.5;
                fx24Oct = fL*2 .^ (0:1/24:log2(fs/2/fL))';
                refLevel = zeros(length(fx24Oct),1)+80;
                %------- frequency responses
                app.UIAxesFrequency.HitTest = "off";
                app.CommonData.LTIFHandle = ...
                    semilogx(app.UIAxesFrequency,fx24Oct,refLevel,"LineWidth",2);
                hold(app.UIAxesFrequency,"on");
                app.CommonData.randTVFHandle = ...
                    semilogx(app.UIAxesFrequency,fx24Oct,refLevel-40,"LineWidth",2);
                app.CommonData.nonLTIFHandle = ...
                    semilogx(app.UIAxesFrequency,fx24Oct,refLevel-30,"LineWidth",2);
                axis(app.UIAxesFrequency,[20 fs/2 minLevel maxLevel])
                grid(app.UIAxesFrequency,"on");
                set(app.UIAxesFrequency,"linewidth",2);
                hold(app.UIAxesFrequency,"off");
                %------- temporal responses
                app.UIAxes_Powerdecay.HitTest = "off";
                fftl = str2double(app.BaselengthDropDown.Value);
                tt = (1:fftl)'/fs;
                app.PeriodmsEditField.Value = num2str(round(tt(end)*1000));
                app.CommonData.LTIipowerHandle = ...
                    plot(app.UIAxes_Powerdecay,tt,zeros(fftl,1)+20,"LineWidth",2);
                hold(app.UIAxes_Powerdecay,"on");
                app.CommonData.LTIimpulseHandle = ...
                    plot(app.UIAxes_Powerdecay,tt,zeros(fftl,1)+maxLevel/2,"LineWidth",2);
                grid(app.UIAxes_Powerdecay,"on");
                set(app.UIAxes_Powerdecay,"linewidth",2);
                axis(app.UIAxes_Powerdecay,[0 tt(end) minLevel maxLevel])
                hold(app.UIAxes_Powerdecay,"off");
            end
            results = 1;
        end

        function results = lnnPinkMeasurement(app)
            fs = str2double(app.SamplingfrequencyHzDropDown.Value);
            fftl = str2double(app.BaselengthDropDown.Value);
            tt = (1:fftl)'/fs;
            ioBufferSize = str2double(app.BuffersizesamplesDropDown.Value);
            bitDepth = app.BitDepthDropDown.Value;
            ioDevice = app.DeviceDropDown.Value;
            playrec = audioPlayerRecorder(fs,"BitDepth",...
                bitDepth,"BufferSize",ioBufferSize,...
                "Device",ioDevice,"SupportVariableSize",true, ...
                "RecorderChannelMapping",1,"PlayerChannelMapping",[1,2]);
            %--- LNN pink noise
            fftl = str2double(app.BaselengthDropDown.Value);
            app.CommonData.fftl = fftl;
            fx = (0:fftl-1)'/fftl*fs;
            switch app.TypeDropDown.Value
                case app.TypeDropDown.Items(2)
                    amp = 1.0 ./ sqrt(max(10,fx));
                    sigFreq = amp.*exp(1i*rand(fftl,1)*2*pi);
                    sigFreq(fftl/2+1:end) = conj(sigFreq(fftl/2+1:-1:2));
                    sigFreq(1) = 0;
                    sigFreq(fftl/2+1) = real(sigFreq(fftl/2+1));
                    lnnPink = real(ifft(sigFreq));
                    lnnPink = lnnPink/std(lnnPink)/20;
                case app.TypeDropDown.Items(3)
                    [lnnPink,~] = audioread("bakHK5dB.wav");
            end
            %--- test signal
            switch app.MaximumdutaionminDropDown.Value
                case app.MaximumdutaionminDropDown.Items(1)
                    signalDuration = 60;
                case app.MaximumdutaionminDropDown.Items(2)
                    signalDuration = 60*2;
                case app.MaximumdutaionminDropDown.Items(3)
                    signalDuration = 60*5;
                case app.MaximumdutaionminDropDown.Items(4)
                    signalDuration = 60*10;
            end
            app.MaximumdutaionminDropDown.Enable = "off";
            xTest = zeros(round(fs)*signalDuration,1);
            capturedData = zeros(round(fs)*signalDuration,1);
            nRepetition = floor(round(fs)*signalDuration/fftl);
            for ii = 1:nRepetition
                xTest((ii-1)*fftl+(1:fftl)) = lnnPink;
            end
            app.CommonData.testSignal = xTest;
            maxLevel = str2double(app.MaximuminputSPLdBDropDown.Value);
            %-----
            fx24Oct = get(app.CommonData.LTIFHandle,"XData");
            fx24OctL = fx24Oct*2^(-1/6);
            fx24OctH = fx24Oct*2^(1/6);
            bw240ct = fx24OctH-fx24OctL;
            nanFiller = fx24Oct+NaN;
            %-----------------
            pause(0.5);
            app.CommonData.running = "on";
            count = 0;
            maxCount = floor(length(xTest)/ioBufferSize);
            app.CommonData.currntLevel = -100;
            setWrapper(app);
            wWrapper = app.CommonData.wWrapper;
            fftlBuffer = zeros(length(wWrapper)+fftl,1);
            nFold = round(length(wWrapper)/fftl);
            initRatency = 2*ioBufferSize;
            while app.CommonData.running == "on" && count < maxCount
                fftlBuffer(1:end-ioBufferSize) = ...
                    fftlBuffer(ioBufferSize+1:end);
                outData = xTest(count*ioBufferSize+(1:ioBufferSize));
                readData = playrec([outData outData]);
                fftlBuffer(end-ioBufferSize+1:end) = readData;
                capturedData(count*ioBufferSize+(1:ioBufferSize)) = ...
                    readData;
                count = count + 1;
                app.LabelSigCount.Text = ...
                    num2str(count*ioBufferSize/fs,"%5.2f");
                if count*ioBufferSize > (nFold+2)*fftl
                    outFftlSig = xTest(count*ioBufferSize+(1-fftl:0));
                    tmp = fftlBuffer(fftl + (1:nFold*fftl)).*wWrapper;
                    tmpPast = fftlBuffer(1:nFold*fftl).*wWrapper;
                    currentSig = tmp(1:fftl);
                    pastSig = tmpPast(1:fftl);
                    for jj = 1:nFold-1
                        currentSig = currentSig+tmp(fftl*jj+(1:fftl));
                        pastSig = pastSig+tmpPast(fftl*jj+(1:fftl));
                    end
                    respF = fft(currentSig)./fft(outFftlSig);
                    respF(1) = 0;
                    iResp = real(ifft(respF));
                    respFn = fft(currentSig-pastSig)./fft(outFftlSig);
                    respFn(1) = 0;
                    pwLTIcumsum = cumsum(fx(2)*abs(respF).^2);
                    pwRVTcumsum = cumsum(fx(2)*abs(respFn).^2);
                    smLTI = (interp1(fx,pwLTIcumsum,fx24OctH,"linear","extrap") ...
                        - interp1(fx,pwLTIcumsum,fx24OctL,"linear","extrap"));
                    smLTI = smLTI(:)./bw240ct(:)/fftl;
                    set(app.CommonData.LTIFHandle,"YData",10*log10(smLTI)+app.CommonData.calibrationdB);
                    smRVT = (interp1(fx,pwRVTcumsum,fx24OctH,"linear","extrap") ...
                        - interp1(fx,pwRVTcumsum,fx24OctL,"linear","extrap"));
                    smRVT = smRVT(:)./bw240ct(:)/fftl/2;
                    set(app.CommonData.randTVFHandle,"YData",10*log10(smRVT)+app.CommonData.calibrationdB);
                    set(app.CommonData.nonLTIFHandle,"YData",nanFiller);
                    cumPw = cumsum(iResp.^2/fs);
                    imsRms = sqrt((cumPw-interp1(tt,cumPw,max(0.001,tt-0.001),"linear","extrap"))*1000);
                    calRespDb = 20*log10(abs(imsRms/mean(imsRms(fftl/4*3:end))))+20;
                    set(app.CommonData.LTIimpulseHandle,"YData", ...
                        (maxLevel*circshift(iResp,-initRatency)/max(abs(iResp))+maxLevel)/2);
                    set(app.CommonData.LTIipowerHandle,"YData", ...
                        circshift(calRespDb,-initRatency));
                end
                drawnow limitrate
            end
            release(playrec);
            app.CommonData.stimulusData = xTest(1:count);
            app.CommonData.caputuredSignal = capturedData(1:count);
            app.STARTButton.Enable = "on";
            app.STOPButton.Enable = "off";
            app.SAVEdataButton.Enable = "on";
            app.BaselengthDropDown.Enable = "on";
            app.MaximumdutaionminDropDown.Enable = "on";
            app.TypeDropDown.Enable = "on";
            app.SpecialistlauncherPanel.Visible = "on";
            app.SpecialistDropDown.Visible = "on";
            app.SpecialistlauncherPanel.Enable = "on";
            app.SpecialistDropDown.Enable = "on";
            app.SpecialistDropDownLabel.Visible = "on";
            results = 1;
        end

        function results = setWrapper(app)
            %fftl = app.CommonData.fftl;
            fftl = str2double(app.BaselengthDropDown.Value);
            switch app.ShaperDropDown.Value
                case app.ShaperDropDown.Items(1)
                    wtmp = [zeros(fftl,1);ones(fftl,1)];
                case app.ShaperDropDown.Items(2)
                    %wtmp = hanning(fftl*2);
                    wtmp = real(ifft(fft(ones(fftl,1),fftl*3) .* fft(hamming(fftl*2),fftl*3)));
                case app.ShaperDropDown.Items(3)
                    wtmp = real(ifft(fft(ones(fftl,1),fftl*4) ...
                        .* fft(blackman(fftl*3),fftl*4)));
            end
            app.CommonData.wWrapper = wtmp/max(wtmp);
            results = 1;
        end
        
        function results = saveTestResult(app)
            fs = str2double(app.SamplingfrequencyHzDropDown.Value);
            outNameBody = append("rpsdR", ...
                string(datetime("now","Format","uuuuMMdd'T'HHmmss")));
            capSignalFullpath = ...
                append(app.CommonData.resultDir,"/",outNameBody,"cap.wav");
            stimulusFullpath = ...
                append(app.CommonData.resultDir,"/",outNameBody,"stm.wav");
            conditionFullpath = ...
                append(app.CommonData.resultDir,"/",outNameBody,"cond.mat");
            switch app.BitDepthDropDown.Value
                case "32-bit float"
                    scaledData = 0.05*app.CommonData.caputuredSignal ./ ...
                        max(abs(app.CommonData.caputuredSignal));
            audiowrite(capSignalFullpath,scaledData, ...
                fs,"BitsPerSample",24);
                otherwise
            audiowrite(capSignalFullpath,app.CommonData.caputuredSignal, ...
                fs,"BitsPerSample",24);
            end
            audiowrite(stimulusFullpath,app.CommonData.stimulusData, ...
                fs,"BitsPerSample",24);
            trialConditions = struct;
            trialConditions.ioDevice = app.CommonData.ioDevice;
            trialConditions.ioDevice = app.CommonData.ioDevice;
            trialConditions.testType = ...
                app.TypeDropDown.Value;
            trialConditions.Shaper = ...
                app.ShaperDropDown.Value;
            trialConditions.InterfaceFormat = ...
                app.BitDepthDropDown.Value;
            trialConditions.InterfaceBufferSize = ...
                app.BuffersizesamplesDropDown.Value;
            trialConditions.FFTsize = ...
                app.BaselengthDropDown.Value;
            %trialConditions.basePath = app.CommonData.basePath;
            %trialConditions.FeedbackLevel = ...
            %    app.FeedbackleveldBDropDown.Value;
            %trialConditions.underRunRecord = app.CommonData.underRunRecord;
            %trialConditions.overRunRecord = app.CommonData.overRunRecord;
            switch app.BitDepthDropDown.Value
                case "32-bit float"
                    trialConditions.trueMaximumLevel = ...
                        max(abs(app.CommonData.caputuredSignal));
            end
            save(conditionFullpath,"trialConditions");
            results = 1;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            t1 = string(datetime("now",'Format','yMMdd'));
            t2 = string(datetime("now",'Format','HHmmss'));
            reportDirName = append("raphsodeeTest",t1,"T",t2);
            app.TextArea.Value = ["実験結果を記録するために"; ...
                reportDirName;...
                "という名前のフォルダーを作ります。作る場所を指定して下さい"];
            app.CommonData.reportDirName = reportDirName;
            setReportDirectory(app);
            playrec = audioPlayerRecorder;
            devices = getAudioDevices(playrec);
            app.AudiointerfacePanel.Enable = "on";
            app.AudiointerfacePanel.Visible = "on";
            devName = ["以下から選んで下さい";string(devices')];
            app.DeviceDropDown.Items = devName;
            app.TextArea.Value = ["左下の Audio interface のメニューから"; ...
                "使用するオーディオインタフェース（Device）を選んで下さい"];
            disableDefaultInteractivity(app.UIAxesFrequency);
            disableDefaultInteractivity(app.UIAxes_Powerdecay);
            app.CommonData.calibrationdB = 0;
        end

        % Value changed function: DeviceDropDown
        function DeviceDropDownValueChanged(app, event)
            ioDevice = app.DeviceDropDown.Value;delimiter = ", ";
            fprintf(app.CommonData.logFid, 'IOdevice selection, %s\n', ...
                append(ioDevice, delimiter, string(datetime)));
            ioPlayRecDeviceAssignAndTest(app);
            if string(app.DeviceDropDown.Value) == string(app.DeviceDropDown.Items(1))
                app.BitDepthDropDown.Enable = "off";
                app.BuffersizesamplesDropDown.Enable = "off";
                app.SamplingfrequencyHzDropDown.Enable = "off";
                app.DeviceDropDown.Enable = "on";
            else
                app.BitDepthDropDown.Enable = "on";
                app.BuffersizesamplesDropDown.Enable = "on";
                app.SamplingfrequencyHzDropDown.Enable = "on";
                initializeGraphics(app);
            end
        end

        % Button pushed function: CalibrationButton
        function CalibrationButtonPushed(app, event)
            app.CalibrationButton.Enable = "off";
            app.AbortButton.Enable = "on";
            app.AbortButton.Visible = "on";
            app.Label_time.Enable = "on";
            app.Label_time.Visible = "on";
            app.dBreMSBLabel.Visible = "on";
            app.TimesLabel.Visible = "on";
            app.TimesLabel.Enable = "on";
            app.LabelforDB.Visible = "on";
            app.LabelforDB.Enable = "on";
            app.TestsignalSPLdBDropDown.Enable = "on";
            app.MaximuminputSPLdBDropDown.Enable = "on";
            app.TestsignalSPLCheckBox.Value = 0;
            fprintf(app.CommonData.logFid, 'Calibration button pushed, %s\n', ...
                string(datetime));
            app.TextArea.Value = ["オーディオインタフェースの出力をまず調整します。音は60秒続きます";...
                "マイクの入力は最小にしておいて下さい"; ...
                "マイクの位置での音圧レベルを騒音計で測定して";...
                "Test signal SPLのメニューにある値になるように出力を調整して下さい";...
                "調整できたら Test signal SPL にチェックをつけて下さい"];
            calibrateInput(app);
        end

        % Value changed function: BitDepthDropDown
        function BitDepthDropDownValueChanged(app, event)
            bitDepth = app.BitDepthDropDown.Value;
            delimiter = ", ";
            fprintf(app.CommonData.logFid, 'Sampling frequency selection, %s\n', ...
                  append(bitDepth, delimiter, string(datetime)));
            ioPlayRecDeviceAssignAndTest(app);
        end

        % Value changed function: SamplingfrequencyHzDropDown
        function SamplingfrequencyHzDropDownValueChanged(app, event)
            samplingFreq = app.SamplingfrequencyHzDropDown.Value;delimiter = ", ";
            fprintf(app.CommonData.logFid, 'Sampling frequency selection, %s\n', ...
                  append(samplingFreq, delimiter, string(datetime)));
            ioPlayRecDeviceAssignAndTest(app);
        end

        % Value changed function: BuffersizesamplesDropDown
        function BuffersizesamplesDropDownValueChanged(app, event)
            bufferSize = app.BuffersizesamplesDropDown.Value;delimiter = ", ";
            fprintf(app.CommonData.logFid, 'Buffer size selection, %s\n', ...
                  append(bufferSize, delimiter, string(datetime))); 
            ioPlayRecDeviceAssignAndTest(app);
        end

        % Button pushed function: OKcalibrationcompletedButton
        function OKcalibrationcompletedButtonPushed(app, event)
            delimiter = ", ";
            fprintf(app.CommonData.logFid, 'Calibration level is: , %s\n', ...
                append(num2str(app.CommonData.calibrationdB), " dB ", delimiter, string(datetime)));
            app.CommonData.running = "off";
            app.TextArea.Value = ["入力系の感度が較正されました"; ...
                append("較正のための値は ", ...
                num2str(app.CommonData.calibrationdB), " dBです")];
            app.OKcalibrationcompletedButton.Enable = "off";
            app.AbortButton.Enable = "off";
            app.MaximuminputSPLdBDropDown.Enable = "off";
            app.OnlinemeasurementPanel.Enable = "on";
            app.OnlinemeasurementPanel.Visible = "on";
            %app.SpecialistlauncherPanel.Enable = "on";
            %app.SpecialistlauncherPanel.Visible = "on";
            initializeGraphics(app);
        end

        % Button pushed function: AbortButton
        function AbortButtonPushed(app, event)
            delimiter = ", ";
            fprintf(app.CommonData.logFid, 'Playrec aborted , %s\n', ...
                append(" **** ", delimiter, string(datetime)));
            app.CommonData.running = "off";
            app.CommonData.aborted = "yes";
            app.OKcalibrationcompletedButton.Enable = "off";
        end

        % Value changed function: TestsignalSPLCheckBox
        function TestsignalSPLCheckBoxValueChanged(app, event)
            value = app.TestsignalSPLCheckBox.Value;delimiter = ", ";
            if value == 1
                app.TestsignalSPLdBDropDown.Enable = "off";
                app.OKcalibrationcompletedButton.Enable = "on";
                app.CommonData.calibrationdB = ...
                    str2double(app.TestsignalSPLdBDropDown.Value) ...
                    - app.CommonData.currntLevel;
                upperTarget = str2double(app.MaximuminputSPLdBDropDown.Value) ...
                    - 25 - app.CommonData.calibrationdB;
                upperTarget = round(upperTarget);
                app.TextArea.Value = ["試験信号の音圧レベルが"; ...
                    append(app.TestsignalSPLdBDropDown.Value, " dB に設定されました");...
                    "次に、入力系の感度を調整して下さい。目標の目安は"; ...
                    append(num2str(upperTarget-10)," dBと ",num2str(upperTarget)," の間です");...
                    "(数dB外れていても構いません)"];
                fprintf(app.CommonData.logFid, 'Calibration checkbox checked, %s\n', ...
                    append(app.TestsignalSPLdBDropDown.Value, delimiter, string(datetime)));
            end
        end

        % Value changed function: MaximuminputSPLdBDropDown
        function MaximuminputSPLdBDropDownValueChanged(app, event)
            %value = app.MaximuminputSPLdBDropDown.Value;
            app.CommonData.calibrationdB = ...
                str2double(app.TestsignalSPLdBDropDown.Value) ...
                - app.CommonData.currntLevel;
            upperTarget = str2double(app.MaximuminputSPLdBDropDown.Value) ...
                - 25 - app.CommonData.calibrationdB;
            upperTarget = round(upperTarget);
            app.TextArea.Value = ["入力信号の最大音圧レベルが"; ...
                append(app.MaximuminputSPLdBDropDown.Value, " dB に設定されました");...
                "入力系の感度を調整し直して下さい。目標の目安は"; ...
                append(num2str(upperTarget-10)," dBと ",num2str(upperTarget)," の間です"); ...
                "(数dB外れていても構いません)"];
        end

        % Value changed function: BaselengthDropDown
        function BaselengthDropDownValueChanged(app, event)
            value = app.BaselengthDropDown.Value;
            fs = str2double(app.SamplingfrequencyHzDropDown.Value);
            period = round(str2double(value)/fs*1000);
            app.PeriodmsEditField.Value = num2str(period);
            initializeGraphics(app);
        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            value = app.TypeDropDown.Value;
            app.ShaperDropDown.Enable = "on";
            switch value
                case app.TypeDropDown.Items(1)
            app.TextArea.Value = ["測定法がリセットされました。"; ...
                "測定法を選び直して下さい。"];
                    app.STARTButton.Enable = "off";
                    app.STOPButton.Enable = "off";
                case app.TypeDropDown.Items(2)
            app.TextArea.Value = ["測定法が"+value+"に設定されました。STATRボタンを押すと測定が始まります。"; ...
                "Maximum durationのメニューを選んで最大の測定時間を設定できます。"; ...
                "STOPボタンを押して途中で測定を止めることもできます。"];
                    app.STARTButton.Enable = "on";
                    app.STOPButton.Enable = "off";
                    app.BaselengthDropDown.Value = app.BaselengthDropDown.Items(2);
                    initializeGraphics(app);
                case app.TypeDropDown.Items(3)
            app.TextArea.Value = ["測定法が"+value+"に設定されました。STATRボタンを押すと測定が始まります。"; ...
                "Maximum durationのメニューを選んで最大の測定時間を設定できます。"; ...
                "STOPボタンを押して途中で測定を止めることもできます。"];
                    app.STARTButton.Enable = "on";
                    app.STOPButton.Enable = "off";
                    app.BaselengthDropDown.Value = app.BaselengthDropDown.Items(5);
                    initializeGraphics(app);
            end
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            delimiter = ", ";
            fprintf(app.CommonData.logFid, 'STOP button pushed, %s\n', ...
                  append(" *** ", delimiter, string(datetime)));
            app.STARTButton.Enable = "on";
            app.STOPButton.Enable = "off";
            app.CommonData.running = "off";
            app.TypeDropDown.Enable = "on";
            app.BaselengthDropDown.Enable = "on";
            app.AudiointerfacePanel.Enable = "on";
            app.ShaperDropDown.Enable = "on";
            app.MaximumdutaionminDropDown.Enable = "on";
            app.CalibrationPanel.Enable = "on";
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            delimiter = ", ";
            fprintf(app.CommonData.logFid, 'START button pushed, %s\n', ...
                  append(" *** ", delimiter, string(datetime)));
            app.STARTButton.Enable = "off";
            app.STOPButton.Enable = "on";
            app.TypeDropDown.Enable = "off";
            app.BaselengthDropDown.Enable = "off";
            app.AudiointerfacePanel.Enable = "off";
            app.ShaperDropDown.Enable = "off";
            app.MaximumdutaionminDropDown.Enable = "off";
            app.CalibrationPanel.Enable = "off";
            app.SAVEdataButton.Enable = "off";
            lnnPinkMeasurement(app);
            app.TextArea.Value = ["測定がおわりました。SAVE dataボタンを押して下さい。"; ...
                "測定結果がファイルに記録されます。"];
        end

        % Value changed function: ShaperDropDown
        function ShaperDropDownValueChanged(app, event)
            shaper = app.ShaperDropDown.Value;delimiter = ", ";
            fprintf(app.CommonData.logFid, 'Shaper selected:, %s\n', ...
                  append(shaper, delimiter, string(datetime)));
            setWrapper(app);
        end

        % Value changing function: Slider_InitialPosition
        function Slider_InitialPositionValueChanging(app, event)
            changingValue = event.Value;
            xData = get(app.CommonData.LTIimpulseHandle,"XData");
            xMin = changingValue;
            mag = 2^app.SliderMagnification.Value;
            xMax = max(xMin+0.001,xData(end)/mag+xMin);
            xLimCurrent = [xMin, xMax];
            set(app.UIAxes_Powerdecay,"XLim",xLimCurrent);
        end

        % Value changing function: SliderMagnification
        function SliderMagnificationValueChanging(app, event)
            changingValue = event.Value;
            xData = get(app.CommonData.LTIimpulseHandle,"XData");
            xMin = app.Slider_InitialPosition.Value;
            mag = 2^changingValue;
            xMax = max(xMin+0.001,xData(end)/mag+xMin);
            xLimCurrent = [xMin, xMax];
            set(app.UIAxes_Powerdecay,"XLim",xLimCurrent);
        end

        % Button pushed function: SAVEdataButton
        function SAVEdataButtonPushed(app, event)
            fprintf(app.CommonData.logFid, 'SAVE button pushed, %s\n', ...
                string(datetime));
            saveTestResult(app);
            app.SAVEdataButton.Enable = "off";
            app.TextArea.Value = ["結果がファイルに記録されました。"; ...
                "次の測定を始めることができます。"];
        end

        % Button pushed function: Voicememo5sButton
        function Voicememo5sButtonPushed(app, event)
            fprintf(app.CommonData.logFid, 'Voicememo button pushed, %s\n', ...
                string(datetime));
            originalMessage = app.TextArea.Value;
            app.TextArea.Value = "緑のランプが点灯している5秒間、録音します";
            app.Voicememo5sButton.Enable = "off";
            app.VoiceMemoCountdown.Visible = "on";
            app.VoiceMemoCountdown.Enable = "on";
            fs = 44100;
            ioBufferSize = 2048;
            silence = zeros(ioBufferSize,1);
            playrec = audioPlayerRecorder(fs,"BitDepth",...
                "24-bit integer","BufferSize",ioBufferSize,...
                "Device",app.CommonData.ioDevice,"SupportVariableSize",true);
            for ii = 30:-1:0
                pause(0.1);
                app.VoiceMemoCountdown.Text = num2str(ii/10);
            end
            app.RecordingLamp.Visible = "on";
            app.RecordingLamp.Enable = "on";
            memoVoice = zeros(round(5*fs),1);
            count = 0;
            maxCount = floor(5*fs/ioBufferSize);
            while count < maxCount
                readData = playrec(silence);
                memoVoice(count*ioBufferSize+(1:ioBufferSize)) = readData;
                count = count + 1;
                app.VoiceMemoCountdown.Text = ...
                    num2str(5-count*ioBufferSize/fs,"%5.2f");
                drawnow
            end
            release(app.CommonData.playrec);
            outName = append("vMemo", ...
                string(datetime("now","Format","uuuuMMdd'T'HHmmss")),".wav");
            outFullpath = append(app.CommonData.resultDir,"/",outName);
            audiowrite(outFullpath,memoVoice,fs,"BitsPerSample",24);
            app.RecordingLamp.Visible = "off";
            app.RecordingLamp.Enable = "off";
            app.VoiceMemoCountdown.Visible = "off";
            app.VoiceMemoCountdown.Enable = "off";
            app.Voicememo5sButton.Enable = "on";
            app.TextArea.Value = originalMessage;
        end

        % Value changed function: TestsignalSPLdBDropDown
        function TestsignalSPLdBDropDownValueChanged(app, event)
            value = app.TestsignalSPLdBDropDown.Value;delimiter = ", ";
            fprintf(app.CommonData.logFid, 'Calibration SPL selection selection, %s\n', ...
                append(value, delimiter, string(datetime)));
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            fprintf(app.CommonData.logFid, 'Session closed, %s\n', ...
                string(datetime));
            fclose(app.CommonData.logFid);
            pause(1);
            delete(app)
        end

        % Value changed function: SpecialistDropDown
        function SpecialistDropDownValueChanged(app, event)
            value = app.SpecialistDropDown.Value;
            switch value
                case app.SpecialistDropDown.Items(2)
                    voiceProfilerExperimentt;
                case app.SpecialistDropDown.Items(3)
                    waveletVisualizer;
                case app.SpecialistDropDown.Items(4)
                    realtimeFoSplViewer;
                case app.SpecialistDropDown.Items(5)
                    vtShapeToSoundTestV28;
                case app.SpecialistDropDown.Items(6)
                    vtlDisplay;
            end
            app.SpecialistDropDown.Value = ...
                        app.SpecialistDropDown.Items(1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxesFrequency = uiaxes(app.UIFigure);
            title(app.UIAxesFrequency, 'Frequency response')
            xlabel(app.UIAxesFrequency, 'frequendy (Hz)')
            ylabel(app.UIAxesFrequency, 'level (dB)')
            zlabel(app.UIAxesFrequency, 'Z')
            app.UIAxesFrequency.Visible = 'off';
            app.UIAxes_Powerdecay = uiaxes(app.UIFigure);
            title(app.UIAxes_Powerdecay, 'Power decay')
            xlabel(app.UIAxes_Powerdecay, 'time (s)')
            ylabel(app.UIAxes_Powerdecay, 'level(dB)')
            zlabel(app.UIAxes_Powerdecay, 'Z')
            app.UIAxes_Powerdecay.Visible = 'off';
            app.CAPRICEPexSGacousticmeasurementtoolRHAPSODEELabel = uilabel(app.UIFigure);
            app.CAPRICEPexSGacousticmeasurementtoolRHAPSODEELabel.Text = 'CAPRICEP+exSG acoustic measurement tool : RHAPSODEE';
            app.AudiointerfacePanel = uipanel(app.UIFigure);
            app.AudiointerfacePanel.Enable = 'off';
            app.AudiointerfacePanel.Title = 'Audio interface';
            app.AudiointerfacePanel.Visible = 'off';
            app.DeviceDropDownLabel = uilabel(app.AudiointerfacePanel);
            app.DeviceDropDownLabel.Text = 'Device';
            app.DeviceDropDown = uidropdown(app.AudiointerfacePanel);
            app.DeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @DeviceDropDownValueChanged, true);
            app.BuffersizesamplesDropDownLabel = uilabel(app.AudiointerfacePanel);
            app.BuffersizesamplesDropDownLabel.Text = 'Buffer size (samples)';
            app.BuffersizesamplesDropDown = uidropdown(app.AudiointerfacePanel);
            app.BuffersizesamplesDropDown.Items = {'2048', '1024', '512', '256', '128', '64'};
            app.BuffersizesamplesDropDown.ValueChangedFcn = createCallbackFcn(app, @BuffersizesamplesDropDownValueChanged, true);
            app.BuffersizesamplesDropDown.Enable = 'off';
            app.BuffersizesamplesDropDown.Value = '1024';
            app.BitDepthDropDownLabel = uilabel(app.AudiointerfacePanel);
            app.BitDepthDropDownLabel.Text = 'BitDepth';
            app.BitDepthDropDown = uidropdown(app.AudiointerfacePanel);
            app.BitDepthDropDown.Items = {'24-bit integer', '32-bit float', '16-bit integer'};
            app.BitDepthDropDown.ValueChangedFcn = createCallbackFcn(app, @BitDepthDropDownValueChanged, true);
            app.BitDepthDropDown.Enable = 'off';
            app.BitDepthDropDown.Value = '24-bit integer';
            app.SamplingfrequencyHzDropDownLabel = uilabel(app.AudiointerfacePanel);
            app.SamplingfrequencyHzDropDownLabel.Text = 'Sampling frequency (Hz)';
            app.SamplingfrequencyHzDropDown = uidropdown(app.AudiointerfacePanel);
            app.SamplingfrequencyHzDropDown.Items = {'44100', '48000', '88200', '96000', '176400', '192000'};
            app.SamplingfrequencyHzDropDown.ValueChangedFcn = createCallbackFcn(app, @SamplingfrequencyHzDropDownValueChanged, true);
            app.SamplingfrequencyHzDropDown.Enable = 'off';
            app.SamplingfrequencyHzDropDown.Value = '44100';
            app.CalibrationPanel = uipanel(app.UIFigure);
            app.CalibrationPanel.Enable = 'off';
            app.CalibrationPanel.Title = 'Calibration';
            app.CalibrationPanel.Visible = 'off';
            app.LabelforDB = uilabel(app.CalibrationPanel);
            app.LabelforDB.Enable = 'off';
            app.LabelforDB.Visible = 'off';
            app.LabelforDB.Text = '-100';
            app.dBreMSBLabel = uilabel(app.CalibrationPanel);
            app.dBreMSBLabel.Enable = 'off';
            app.dBreMSBLabel.Visible = 'off';
            app.dBreMSBLabel.Text = 'dB re. MSB';
            app.CalibrationButton = uibutton(app.CalibrationPanel, 'push');
            app.CalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrationButtonPushed, true);
            app.CalibrationButton.Enable = 'off';
            app.CalibrationButton.Text = 'Calibration';
            app.TimesLabel = uilabel(app.CalibrationPanel);
            app.TimesLabel.Enable = 'off';
            app.TimesLabel.Visible = 'off';
            app.TimesLabel.Text = 'Time (s)';
            app.Label_time = uilabel(app.CalibrationPanel);
            app.Label_time.Enable = 'off';
            app.Label_time.Visible = 'off';
            app.Label_time.Text = '0';
            app.AbortButton = uibutton(app.CalibrationPanel, 'push');
            app.AbortButton.ButtonPushedFcn = createCallbackFcn(app, @AbortButtonPushed, true);
            app.AbortButton.Enable = 'off';
            app.AbortButton.Visible = 'off';
            app.AbortButton.Text = 'Abort';
            app.TestsignalSPLdBDropDownLabel = uilabel(app.CalibrationPanel);
            app.TestsignalSPLdBDropDownLabel.Text = 'Test signal SPL (dB)';
            app.TestsignalSPLdBDropDown = uidropdown(app.CalibrationPanel);
            app.TestsignalSPLdBDropDown.Items = {'82', '81', '80', '79', '78', '77', '76', '75', '74', '73', '72', '71', '70', '69', '68'};
            app.TestsignalSPLdBDropDown.ValueChangedFcn = createCallbackFcn(app, @TestsignalSPLdBDropDownValueChanged, true);
            app.TestsignalSPLdBDropDown.Enable = 'off';
            app.TestsignalSPLdBDropDown.Value = '80';
            app.MaximuminputSPLdBDropDownLabel = uilabel(app.CalibrationPanel);
            app.MaximuminputSPLdBDropDownLabel.Text = 'Maximum input SPL (dB)';
            app.MaximuminputSPLdBDropDown = uidropdown(app.CalibrationPanel);
            app.MaximuminputSPLdBDropDown.Items = {'100', '105', '110', '115', '120', '125', '130', '135'};
            app.MaximuminputSPLdBDropDown.ValueChangedFcn = createCallbackFcn(app, @MaximuminputSPLdBDropDownValueChanged, true);
            app.MaximuminputSPLdBDropDown.Enable = 'off';
            app.MaximuminputSPLdBDropDown.Value = '105';
            app.OKcalibrationcompletedButton = uibutton(app.CalibrationPanel, 'push');
            app.OKcalibrationcompletedButton.ButtonPushedFcn = createCallbackFcn(app, @OKcalibrationcompletedButtonPushed, true);
            app.OKcalibrationcompletedButton.Enable = 'off';
            app.OKcalibrationcompletedButton.Text = 'OK: calibration completed';
            app.TestsignalSPLCheckBox = uicheckbox(app.CalibrationPanel);
            app.TestsignalSPLCheckBox.ValueChangedFcn = createCallbackFcn(app, @TestsignalSPLCheckBoxValueChanged, true);
            app.TestsignalSPLCheckBox.Text = 'Test signal SPL';
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = '説明';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.Voicememo5sButton = uibutton(app.UIFigure, 'push');
            app.Voicememo5sButton.ButtonPushedFcn = createCallbackFcn(app, @Voicememo5sButtonPushed, true);
            app.Voicememo5sButton.Visible = 'off';
            app.Voicememo5sButton.Text = 'Voice memo 5s';
            app.VoiceMemoCountdown = uilabel(app.UIFigure);
            app.VoiceMemoCountdown.Visible = 'off';
            app.VoiceMemoCountdown.Text = '0';
            app.RecordingLampLabel = uilabel(app.UIFigure);
            app.RecordingLampLabel.Visible = 'off';
            app.RecordingLampLabel.Text = 'Recording';
            app.RecordingLamp = uilamp(app.UIFigure);
            app.RecordingLamp.Visible = 'off';
            app.OnlinemeasurementPanel = uipanel(app.UIFigure);
            app.OnlinemeasurementPanel.Enable = 'off';
            app.OnlinemeasurementPanel.Title = 'On-line measurement';
            app.OnlinemeasurementPanel.Visible = 'off';
            app.TypeDropDownLabel = uilabel(app.OnlinemeasurementPanel);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.OnlinemeasurementPanel);
            app.TypeDropDown.Items = {'測定法を選んで下さい', 'LNN Pink noise', 'BakonHK5dB'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = '測定法を選んで下さい';
            app.STARTButton = uibutton(app.OnlinemeasurementPanel, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Enable = 'off';
            app.STARTButton.Text = 'START';
            app.STOPButton = uibutton(app.OnlinemeasurementPanel, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Enable = 'off';
            app.STOPButton.Text = 'STOP';
            app.BaselengthDropDownLabel = uilabel(app.OnlinemeasurementPanel);
            app.BaselengthDropDownLabel.Text = 'Base length';
            app.BaselengthDropDown = uidropdown(app.OnlinemeasurementPanel);
            app.BaselengthDropDown.Items = {'8192', '16384', '32768', '65536', '131072', '262144', '524288', '1048576'};
            app.BaselengthDropDown.ValueChangedFcn = createCallbackFcn(app, @BaselengthDropDownValueChanged, true);
            app.BaselengthDropDown.Value = '16384';
            app.PeriodmsEditFieldLabel = uilabel(app.OnlinemeasurementPanel);
            app.PeriodmsEditFieldLabel.Text = 'Period (ms)';
            app.PeriodmsEditField = uieditfield(app.OnlinemeasurementPanel, 'text');
            app.PeriodmsEditField.Editable = 'off';
            app.PeriodmsEditField.Value = '743';
            app.LabelSigCount = uilabel(app.OnlinemeasurementPanel);
            app.LabelSigCount.Text = '0';
            app.ShaperDropDownLabel = uilabel(app.OnlinemeasurementPanel);
            app.ShaperDropDownLabel.Text = 'Shaper';
            app.ShaperDropDown = uidropdown(app.OnlinemeasurementPanel);
            app.ShaperDropDown.Items = {'none', 'Hamming', 'Blackman'};
            app.ShaperDropDown.ValueChangedFcn = createCallbackFcn(app, @ShaperDropDownValueChanged, true);
            app.ShaperDropDown.Enable = 'off';
            app.ShaperDropDown.Value = 'Blackman';
            app.MaximumdutaionminDropDownLabel = uilabel(app.OnlinemeasurementPanel);
            app.MaximumdutaionminDropDownLabel.Text = 'Maximum dutaion (min)';
            app.MaximumdutaionminDropDown = uidropdown(app.OnlinemeasurementPanel);
            app.MaximumdutaionminDropDown.Items = {'1-minute', '2-minutes', '5-minutes', '10-minutes'};
            app.MaximumdutaionminDropDown.Value = '2-minutes';
            app.SAVEdataButton = uibutton(app.OnlinemeasurementPanel, 'push');
            app.SAVEdataButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEdataButtonPushed, true);
            app.SAVEdataButton.Enable = 'off';
            app.SAVEdataButton.Text = 'SAVE data';
            app.SpecialistlauncherPanel = uipanel(app.UIFigure);
            app.SpecialistlauncherPanel.Enable = 'off';
            app.SpecialistlauncherPanel.Title = 'Specialist launcher';
            app.SpecialistlauncherPanel.Visible = 'off';
            app.SpecialistDropDownLabel = uilabel(app.SpecialistlauncherPanel);
            app.SpecialistDropDownLabel.Text = 'Specialist';
            app.SpecialistDropDown = uidropdown(app.SpecialistlauncherPanel);
            app.SpecialistDropDown.Items = {'--- Select specialist ----', 'Valied Voice Range Profiler', 'Wavelet Visualizer', 'realtime fo trajectory', 'Voice Production Simulator', 'Vocal tract shape display'};
            app.SpecialistDropDown.ValueChangedFcn = createCallbackFcn(app, @SpecialistDropDownValueChanged, true);
            app.SpecialistDropDown.Value = '--- Select specialist ----';
            app.MagnifyLabel = uilabel(app.UIFigure);
            app.MagnifyLabel.Text = 'Magnify';
            app.SliderMagnification = uislider(app.UIFigure);
            app.SliderMagnification.Limits = [0 5];
            app.SliderMagnification.MajorTicks = [0 1 2 3 4 5];
            app.SliderMagnification.MajorTickLabels = {'1', '2', '4', '8', '16', '32'};
            app.SliderMagnification.ValueChangingFcn = createCallbackFcn(app, @SliderMagnificationValueChanging, true);
            app.SliderMagnification.MinorTicks = [];
            app.SliderMagnification.Visible = 'off';
            app.PositionLabel = uilabel(app.UIFigure);
            app.PositionLabel.Text = 'Position';
            app.Slider_InitialPosition = uislider(app.UIFigure);
            app.Slider_InitialPosition.Limits = [0 0.12];
            app.Slider_InitialPosition.ValueChangingFcn = createCallbackFcn(app, @Slider_InitialPositionValueChanging, true);
            app.Slider_InitialPosition.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = raphsodeeAcousticTool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end